% description: Z-Displacement and von Mises Stress computation.
% Copyright 2014-2018 The MathWorks, Inc.
classdef DeflectionAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StrainedBracketDeflection       matlab.ui.Figure
        MaterialPropertiesPanel         matlab.ui.container.Panel
        PoissonsRatioEditFieldLabel     matlab.ui.control.Label
        PoissonsRatioEditField          matlab.ui.control.NumericEditField
        YoungsModulusGPaEditFieldLabel  matlab.ui.control.Label
        YoungsModulusGPaEditField       matlab.ui.control.NumericEditField
        Defl_Plot                       matlab.ui.control.UIAxes
        MaterialsListBoxLabel           matlab.ui.control.Label
        MaterialsListBox                matlab.ui.control.ListBox
        FindDeflectionButton            matlab.ui.control.Button
        ProcessingLabel                 matlab.ui.control.Label
        MaxDeflectionmEditFieldLabel    matlab.ui.control.Label
        MaxDeflection                   matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
    end

    
    properties (Access = public)
        DataDir
        parfile
        SelectedMaterial
        data
        model
        result
        Deformation
        counter
    end
    
    methods (Access = private)
        
        function updateMaterials(app)
            if isempty(app.parfile)
                file = dir(fullfile(app.DataDir,'*.txt'));
                if isempty(file)
                    answer = questdlg('Would you like to select a different folder?', ...
               	        'File Not Found', 'Yes','No/Exit','Yes');
                    % Handle response
                    switch answer
                        case 'Yes'
                            disp([answer '...Please select new folder'])
                            app.DataDir = uigetdir(pwd, ' Select Parameter File Directory');
                            if isnumeric(app.DataDir)
                                error(' User cancelled');
                            end
                            if isempty(dir(fullfile(app.DataDir,'*.txt')))
                                error('No Materials File Found')
                            end
                            
                        case 'No/Exit'
                            disp([answer '...Exiting'])
                            error('User cancelled')
                            
                    end
                end
                
                app.parfile = dir(fullfile(app.DataDir,'*.txt'));
                materialsfile = app.parfile.name;
            else
                materialsfile = app.parfile.name;
            end
            
            mats = readtable(fullfile(app.DataDir,materialsfile),'ReadVariableNames',false,'HeaderLines',1);
            mats.Properties.VariableNames = {'Material','YoungsModulus','PoissonsRatio'};
            app.data = mats;
            app.MaterialsListBox.Items = mats.Material;
            app.MaterialsListBox.Value = mats.Material(1);
            app.SelectedMaterial = mats{1,1};
            defineMat(app)
        end
        
        function defineMat(app)
            if ~isempty(app.data)
                idx = strcmp(app.data{:,1},app.SelectedMaterial);
                app.YoungsModulusGPaEditField.Value = app.data{idx,2};
                app.PoissonsRatioEditField.Value = app.data{idx,3};
            end
        end


    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, DataDir)
            app.ProcessingLabel.Visible = 'Off';
            app.MaxDeflection.Visible = 'Off';
            app.MaxDeflectionmEditFieldLabel.Visible = 'Off';
            app.Defl_Plot.Visible = 'Off';
            app.Switch.Visible = 'Off';
            app.counter = 0;
            
            app.parfile = dir(fullfile(fileparts(which(mfilename)),'*.txt'));
            if isempty(app.parfile)
                app.DataDir = uigetdir(pwd, 'Select Parameter File Directory');
                if isnumeric(app.DataDir)
                    error('User cancelled');
                end
            else
                app.DataDir = app.parfile.folder;
            end
            
            updateMaterials(app);
            app.model = createpde('structural','static-solid');
            importGeometry(app.model,'BracketWithHole.stl');
            structuralBC(app.model,'Face',4,'Constraint','fixed');
        end

        % Value changed function: MaterialsListBox
        function MaterialsListBoxValueChanged(app, event)
            hh= app.Defl_Plot;
            reset(hh.Title);
            colorbar(hh,'off');
            cla(hh);
            app.MaxDeflection.Visible = 'Off';
            app.MaxDeflectionmEditFieldLabel.Visible = 'Off';
            app.Defl_Plot.Visible = 'Off';
            app.Switch.Visible = 'Off';
            valueM = app.MaterialsListBox.Value;
            app.SelectedMaterial = valueM;
            defineMat(app)
        end

        % Button pushed function: FindDeflectionButton
        function FindDeflectionButtonPushed(app, event)
            app.ProcessingLabel.Visible = 'On';
            YM = app.YoungsModulusGPaEditField.Value*1e9;
            PR = app.PoissonsRatioEditField.Value;
            structuralProperties(app.model,'Cell',1,'YoungsModulus',YM, ...
                'PoissonsRatio',PR);
            distributedLoad = 1e4; % Applied load in Pascals
            structuralBoundaryLoad (app.model,'Face',8,'SurfaceTraction',[0;0;-distributedLoad]);
            bracketThickness = 1e-2; % Thickness of horizontal plate with hole, meters
            generateMesh(app.model,'Hmax',bracketThickness);
            app.result = solve(app.model);
            app.Deformation = struct('ux',app.result.Displacement.ux,'uy',app.result.Displacement.uy,...
                'uz',app.result.Displacement.uz);
            DefMag = app.result.Displacement.Magnitude; % Not used here
            cla(app.Defl_Plot)
            if strcmp(app.Switch.Value,'von Mises Stress')
                colormapdat = app.result.VonMisesStress;
                pde3DResults(app.Defl_Plot,app.model,1,'ColorMapData',colormapdat,...
                    'Deformation',app.Deformation,'DeformationScaleFactor',6e2);
                colormap(app.Defl_Plot,'jet')
                caxis(app.Defl_Plot,'auto')
                ResultOutput = max(colormapdat);
                app.MaxDeflectionmEditFieldLabel.Text = 'Max von Mises Stress (Pa)';
            else
                colormapdat = app.Deformation.uz;
                pde3DResults(app.Defl_Plot,app.model,1,'ColorMapData',colormapdat,...
                    'Deformation',app.Deformation,'DeformationScaleFactor',6e2);
                cm = colormap(app.Defl_Plot);
                colormap(app.Defl_Plot,flip(cm));
                caxis(app.Defl_Plot,[min(app.Deformation.uz) 0])
                ResultOutput = min(colormapdat);
                app.MaxDeflectionmEditFieldLabel.Text = 'Max Deflection (m)';
            end
            
            title(app.Defl_Plot,app.Switch.Value)
            
            app.ProcessingLabel.Visible = 'Off';
            app.MaxDeflectionmEditFieldLabel.Visible = 'On';
            app.MaxDeflection.Visible = 'On';
            app.MaxDeflection.Value = ResultOutput;
            
            app.Switch.Visible = 'On';
            
            % Plot for region deflection/Comment plot above
            %TipNodes = findNodes(app.model.Mesh,'region','Edge',24);
            %resultz = result.Displacement.uz(TipNodes,:);
            %plot(app.Defl_Plot,resultz)
        end

        % Close request function: StrainedBracketDeflection
        function StrainedBracketDeflectionCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: Switch
        function SwitchChanged(app, event)
            value = app.Switch.Value;
            title(app.Defl_Plot,app.Switch.Value)
            if strcmp(value,'von Mises Stress')
                pde3DResults(app.Defl_Plot,app.model,1,'ColorMapData',app.result.VonMisesStress,...
                    'Deformation',app.Deformation,'DeformationScaleFactor',6e2);
                colormap(app.Defl_Plot,'jet')
                caxis(app.Defl_Plot,'auto');
                app.MaxDeflectionmEditFieldLabel.Text = 'Max von Mises Stress (Pa)';
                app.MaxDeflection.Value = max(app.result.VonMisesStress);
            else
                pde3DResults(app.Defl_Plot,app.model,1,'ColorMapData',app.Deformation.uz,...
                    'Deformation',app.Deformation,'DeformationScaleFactor',6e2);
                cm = colormap(app.Defl_Plot);
                colormap(app.Defl_Plot,flip(cm));
                caxis(app.Defl_Plot,[min(app.Deformation.uz) 0])
                app.MaxDeflectionmEditFieldLabel.Text = 'Max Deflection (m)';
                app.MaxDeflection.Value = min(app.Deformation.uz);
            end
        end

        % Value changed function: YoungsModulusGPaEditField
        function YoungsModulusGPaEditFieldValueChanged(app, event)
            hh= app.Defl_Plot;
            reset(hh.Title);
            colorbar(hh,'off');
            cla(hh);
            app.MaxDeflection.Visible = 'Off';
            app.MaxDeflectionmEditFieldLabel.Visible = 'Off';
            app.Defl_Plot.Visible = 'Off';
            app.Switch.Visible = 'Off';
            
            cYM = app.YoungsModulusGPaEditField.Value;
            
            if any(app.data{:,2}==cYM) && any(app.data{(app.data{:,2}==cYM),3} == app.PoissonsRatioEditField.Value)
                idxC = app.data{:,2}==cYM & (app.data{:,3} == app.PoissonsRatioEditField.Value);
                app.SelectedMaterial = app.data{idxC,1};
                app.MaterialsListBox.Value = app.data.Material(idxC);
                
            else
                app.counter = app.counter + 1;
                ctmat.Material = "Custom " + app.counter;
                ctmat.YoungsModulus =cYM;
                ctmat.PoissonsRatio = app.PoissonsRatioEditField.Value;
                app.data = [struct2table(ctmat);app.data];
                app.MaterialsListBox.Items = app.data.Material;
                app.MaterialsListBox.Value = app.data.Material(1);
                app.SelectedMaterial = app.data{1,1};
            end
            
        end

        % Value changed function: PoissonsRatioEditField
        function PoissonsRatioEditFieldValueChanged(app, event)
            hh= app.Defl_Plot;
            reset(hh.Title);
            colorbar(hh,'off');
            cla(hh);
            app.MaxDeflection.Visible = 'Off';
            app.MaxDeflectionmEditFieldLabel.Visible = 'Off';
            app.Defl_Plot.Visible = 'Off';
            app.Switch.Visible = 'Off';
            
            cPR = app.PoissonsRatioEditField.Value;
            
            if any(app.data{:,3}==cPR) && any(app.data{(app.data{:,3}==cPR),2} == app.YoungsModulusGPaEditField.Value)
                idxC = app.data{:,3}==cPR & (app.data{:,2} == app.YoungsModulusGPaEditField.Value);
                app.SelectedMaterial = app.data{idxC,1};
                app.MaterialsListBox.Value = app.data.Material(idxC);
                
            else
                app.counter = app.counter + 1;
                ctmat.Material = "Custom " + app.counter;
                ctmat.YoungsModulus =app.YoungsModulusGPaEditField.Value;
                ctmat.PoissonsRatio = cPR;
                app.data = [struct2table(ctmat);app.data];
                app.MaterialsListBox.Items = app.data.Material;
                app.MaterialsListBox.Value = app.data.Material(1);
                app.SelectedMaterial = app.data{1,1};
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StrainedBracketDeflection = uifigure;
            app.StrainedBracketDeflection.Name = 'Strained Bracket Deflection';
            app.StrainedBracketDeflection.CloseRequestFcn = createCallbackFcn(app, @StrainedBracketDeflectionCloseRequest, true);
            app.MaterialPropertiesPanel = uipanel(app.StrainedBracketDeflection);
            app.MaterialPropertiesPanel.Title = 'Material Properties';
            app.PoissonsRatioEditFieldLabel = uilabel(app.MaterialPropertiesPanel);
            app.PoissonsRatioEditFieldLabel.Text = 'Poisson''s Ratio';
            app.PoissonsRatioEditField = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.PoissonsRatioEditField.Limits = [0 Inf];
            app.PoissonsRatioEditField.ValueChangedFcn = createCallbackFcn(app, @PoissonsRatioEditFieldValueChanged, true);
            app.PoissonsRatioEditField.Value = 0.1;
            app.YoungsModulusGPaEditFieldLabel = uilabel(app.MaterialPropertiesPanel);
            app.YoungsModulusGPaEditFieldLabel.Text = 'Young''s Modulus [GPa]';
            app.YoungsModulusGPaEditField = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.YoungsModulusGPaEditField.Limits = [1 Inf];
            app.YoungsModulusGPaEditField.ValueChangedFcn = createCallbackFcn(app, @YoungsModulusGPaEditFieldValueChanged, true);
            app.YoungsModulusGPaEditField.Value = 1;
            app.Defl_Plot = uiaxes(app.StrainedBracketDeflection);
            app.Defl_Plot.XTick = [];
            app.Defl_Plot.XTickLabel = {'0'};
            app.Defl_Plot.YTick = 0;
            app.Defl_Plot.YTickLabel = {'0'};
            app.MaterialsListBoxLabel = uilabel(app.StrainedBracketDeflection);
            app.MaterialsListBoxLabel.Text = 'Materials';
            app.MaterialsListBox = uilistbox(app.StrainedBracketDeflection);
            app.MaterialsListBox.Items = {};
            app.MaterialsListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialsListBoxValueChanged, true);
            app.MaterialsListBox.Value = {};
            app.FindDeflectionButton = uibutton(app.StrainedBracketDeflection, 'push');
            app.FindDeflectionButton.ButtonPushedFcn = createCallbackFcn(app, @FindDeflectionButtonPushed, true);
            app.FindDeflectionButton.Text = 'Find Deflection';
            app.ProcessingLabel = uilabel(app.StrainedBracketDeflection);
            app.ProcessingLabel.Text = 'Calculating...';
            app.MaxDeflectionmEditFieldLabel = uilabel(app.StrainedBracketDeflection);
            app.MaxDeflectionmEditFieldLabel.Text = 'Max Deflection (m)';
            app.MaxDeflection = uieditfield(app.StrainedBracketDeflection, 'numeric');
            app.MaxDeflection.LowerLimitInclusive = 'off';
            app.MaxDeflection.UpperLimitInclusive = 'off';
            app.MaxDeflection.Editable = 'off';
            app.Label = uilabel(app.StrainedBracketDeflection);
            app.Label.Text = '';
            app.Switch = uiswitch(app.StrainedBracketDeflection, 'slider');
            app.Switch.Items = {'Z-Deflection', 'von Mises Stress'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchChanged, true);
            app.Switch.Value = 'Z-Deflection';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DeflectionAnalysisApp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StrainedBracketDeflection)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StrainedBracketDeflection)
        end
    end
end